classdef RIS_app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SeesweepCheckBox                matlab.ui.control.CheckBox
        ApplyButton                     matlab.ui.control.Button
        AngleresolutionofRISButtonGroup  matlab.ui.container.ButtonGroup
        infiniteButton                  matlab.ui.control.RadioButton
        bitButton_2                     matlab.ui.control.RadioButton
        bitButton                       matlab.ui.control.RadioButton
        AlgorithmButtonGroup            matlab.ui.container.ButtonGroup
        ProposedAlgoButton              matlab.ui.control.RadioButton
        PerfectCSIButton                matlab.ui.control.RadioButton
        d_RIS2UESliderLabel             matlab.ui.control.Label
        d_RIS2UESlider                  matlab.ui.control.Slider
        searchanglegranularityDropDown  matlab.ui.control.DropDown
        searchanglegranularityDropDownLabel  matlab.ui.control.Label
        nHEditField                     matlab.ui.control.NumericEditField
        nHEditFieldLabel                matlab.ui.control.Label
        d_AP2RISmEditField              matlab.ui.control.NumericEditField
        d_AP2RISmEditFieldLabel         matlab.ui.control.Label
        heightofRISmEditField           matlab.ui.control.NumericEditField
        heightofRISmEditFieldLabel      matlab.ui.control.Label
        ImpingingElevationdegEditField  matlab.ui.control.NumericEditField
        ImpingingElevationdegEditFieldLabel  matlab.ui.control.Label
        ImpingingAzimuthdegEditField    matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        UEAzimuthKnobLabel              matlab.ui.control.Label
        UEElevationKnob_2Label          matlab.ui.control.Label
        UEAzimuthKnob                   matlab.ui.control.Knob
        UEElevationKnob_2               matlab.ui.control.Knob
        NonTunableParametersLabel       matlab.ui.control.Label
        RISDashboardLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        h_AP2RIS ; 
        h_RIS2UE ; 
        Psi_optimum;
        Psi_suboptimum_1bit;
        Psi_suboptimum_2bit;
        Psi_proposedalgo
        Psi_proposedalgo_1bit;
        Psi_proposedalgo_2bit;    

    end
    

    properties (Access = private,Constant)

        eigen_direction_azim =(repelem(pi*(0:15),16)).';
        eigen_direction_elev =repmat((pi*(0:15)).',[16,1]);
        nH = 16;
        theta_i = 0;
        phi_i = 0;
        h_RIS = 1.5;
        d_AP2RIS = 3.14;
        lambda = physconst('LightSpeed')/(((5.150 +5.875)/2)*1e9) ; 
        % granularity_for_ue_search = pi/64;
        gradations_to_plot_beam = 200;

    end


    methods (Access = private)
        function  draw_static_components(app)

            
            reflected_azim = -(pi/180)*app.UEAzimuthKnob.Value;% It should vary from 0 to -pi/2. 0 = partition wall, -pi/2 is -y axis
            reflected_elev = (pi/180)*app.UEElevationKnob_2.Value; % It should vary from -pi/2 to +pi/2. +ve value means RIS is below the level of UE
            
            impinging_azim = (pi/180)*app.theta_i; % It should vary from pi/2 to 0. 0 = partition wall, pi/2 is the surface where RIS is kept.
            impinging_elev = (pi/180)*app.phi_i; % It should vary from -pi/2 to +pi/2. +ve value means RIS is below the level of AP
            
            dist_ris2ue = app.d_RIS2UESlider.Value;
            dist_ap2ris = app.d_AP2RIS;
            
            app.heightofRISmEditField.Value = 1.5;
            height_of_ris  = app.heightofRISmEditField.Value ;
            height_of_ap   = height_of_ris + (dist_ap2ris*sin(impinging_elev));
            height_of_ue   = height_of_ris + (dist_ris2ue*sin(reflected_elev));app.UEElevationKnob_2.Value;


            %% Draw RIS Plane

            width_RIS = app.nH*app.lambda/2;
            length_RIS = app.nH*app.lambda/2;
            y = linspace(-width_RIS/2,width_RIS/2,app.nH);
            a=0;b=1;c=0;d=0;
            x = linspace(-length_RIS/2,length_RIS/2,app.nH);
            [z2,x2]=meshgrid(x ,y );
            y2 = (1/b).*(d+(a.*x2)+(c.*z2));
            z2 = z2 + height_of_ris ;
            surf(app.UIAxes, y2,x2,z2,'FaceColor','none');xlabel(app.UIAxes,"x axis (in meter)");ylabel(app.UIAxes,"y axis (in meter)");zlabel(app.UIAxes,"z axis (in meter)");
            text(app.UIAxes,0,0,height_of_ris,'RIS');


            %% Draw x,y,z axes lines 

            % view(app.UIAxes,[1 -1 1]);
            axes_len = 3.14;
            line(app.UIAxes,[0,axes_len],[0,0],[0,0],'Color', 'k', 'LineWidth', 2);hold(app.UIAxes, 'on');
            line(app.UIAxes,[0,0],[-axes_len,axes_len],[0,0],'Color', 'k', 'LineWidth', 2);hold(app.UIAxes, 'on');
            line(app.UIAxes,[0,0],[0,0],[0,axes_len],'Color', 'k', 'LineWidth', 2);hold(app.UIAxes, 'on');
            
            %% Draw obstacle (wall) between UE and AP 

            door_width = 0.5; % All distances in meters
            room_height = 3;
            wall_width = 0.1;
            room_length = 5;
            hold(app.UIAxes, 'on');
            h = fill3(app.UIAxes,[ 0  room_length  room_length  0]+door_width,  [0 0 wall_width wall_width],         [0 0 0  0]  ,'b', ...
                      [ 0  room_length  room_length  0]+door_width, [0 0 wall_width wall_width],          [room_height room_height room_height room_height],'b', ...
                      [ 0  room_length  room_length  0]+door_width,  [0 0 0 0],                            [ 0 0 room_height room_height],'b',...
                      [ 0  room_length  room_length  0]+door_width, [wall_width wall_width wall_width wall_width],[ 0 0 room_height room_height],'b',...
                      [0 0 0 0]+door_width, [0 0 wall_width wall_width], [0 room_height room_height 0],'b',...
                      [room_length room_length room_length room_length]+door_width, [0 0 wall_width wall_width], [0 room_height room_height 0],'b');

            set(h,'FaceAlpha',0.4) ;

            %% Draw LoS ray - RIS to UE(Negative azim angle)

            [x_source,y_source,z_source] = sph2cart(reflected_azim,reflected_elev,dist_ris2ue);
            hold(app.UIAxes, 'on');
            quiver3(app.UIAxes,0,0,height_of_ris,x_source,y_source,z_source,1,'LineWidth',3,'Color','r');
            text(app.UIAxes,x_source,y_source,height_of_ue,'UE');
            hold(app.UIAxes, 'on');
            stem3(app.UIAxes,x_source,y_source,height_of_ue,"filled",'LineWidth',2,'Color','g');
            
            %% Draw LoS ray - AP to RIS(Positive azim angle)
            
            
            [x_source,y_source,z_source] = sph2cart(impinging_azim,impinging_elev,dist_ap2ris);
            hold(app.UIAxes, 'on');
            quiver3(app.UIAxes,x_source,y_source,height_of_ap,-x_source,-y_source,height_of_ris-height_of_ap,1,'LineWidth',3,'Color','r');
            text(app.UIAxes,x_source,y_source,z_source+height_of_ap,'AP');
            hold(app.UIAxes, 'on');
            stem3(app.UIAxes,x_source,y_source,height_of_ap,"filled",'LineWidth',2,'Color','g');

            % view(app.UIAxes,[1 -1 1]);
            end
        
        function h = LosChan256x1_AP2RIS(app)
            azim = (pi/180)*app.ImpingingAzimuthdegEditField.Value;
            elev = (pi/180)*app.ImpingingElevationdegEditField.Value;

            arv1 = exp(-1i*pi*(0:(app.nH-1))*sin(azim)*cos(elev)).'; % RIS Reflector Array Phase Response
            arv2 = exp(-1i*pi*(0:(app.nH-1))*sin(elev)).';
            arv  = kron(arv1,arv2);
            initial_random_phase = 1;%exp(1i*2*pi*rand);
            h  = arv*initial_random_phase;
        end


        function h = LosChan1x256_RIS2UE(app) % size(data) = [ 256x1 ] 

            azim = -(pi/180)*app.UEAzimuthKnob.Value;
            elev = (pi/180)*app.UEElevationKnob_2.Value;

            arv1 = exp(-1i*pi*(0:(app.nH-1))*sin(azim)*cos(elev)).'; % RIS Reflector Array Phase Response
            arv2 = exp(-1i*pi*(0:(app.nH-1))*sin(elev)).';
            arv  = kron(arv1,arv2);
            initial_random_phase = 1;%exp(1i*2*pi*rand);
            h  = arv*initial_random_phase;

        end
        function generate_channels(app)
            app.h_AP2RIS = LosChan256x1_AP2RIS(app);
            app.h_RIS2UE = LosChan1x256_RIS2UE(app);
        end


        function plot_BF_pattern(app,BF)%(BF,height_RIS)

            angleGrid= linspace(-pi/2,pi/2,app.gradations_to_plot_beam);
            [azimGrid,elevGrid] = meshgrid(angleGrid, angleGrid);
            norm_BF = BF/max(max(BF));
            magnification_factor = 4;
            [X, Y, Z]   = sph2cart(-azimGrid,-elevGrid, magnification_factor*norm_BF);
            % colormap turbo

            % For opaque Beam shape
            surf(app.UIAxes,X,Y,Z+app.h_RIS, norm_BF);

            % For transparent Beam shape

            % BFsurface = surf(app.UIAxes,X,Y,Z+app.h_RIS, norm_BF);
            % view([1 1 1])
            % set(BFsurface,'LineStyle','none','FaceAlpha',0.5,'Tag','3D polar plot');
        end

        function BF = BF_pattern(app,psi_sweep)

            if(nargin ==1)
                algo = app.AlgorithmButtonGroup.SelectedObject.Text;
                res = app.AngleresolutionofRISButtonGroup.SelectedObject.Text;
                if(strcmp(algo,"Perfect CSI"))
                    algo =1;
                else
                    algo =2;
                end
    
                if(strcmp(res,"1-bit "))
                    res =1;
                elseif(strcmp(res,"2-bit"))
                    res =2;
                else
                    res =3;
                end


                if(algo == 1)  % Perfect CSI Algorithm 
                    if(res == 1)
                        Psi = app.Psi_suboptimum_1bit;
                    elseif(res ==2)
                        Psi = app.Psi_suboptimum_2bit;
                    else
                        Psi = app.Psi_optimum;
                    end
                else    % Proposed Algorithm
                    if(res == 1)
                        Psi = app.Psi_proposedalgo_1bit;
                    elseif(res ==2)
                        Psi = app.Psi_proposedalgo_2bit;
                    else
                        Psi = app.Psi_proposedalgo;
                    end
                end
    
                angleGrid= linspace(-pi/2,pi/2,app.gradations_to_plot_beam);
                [azimGrid,elevGrid] = meshgrid(angleGrid, angleGrid);
                BF = zeros(size(azimGrid));
                h = conj(app.h_AP2RIS).*exp(-1i*Psi);
            
                for i = 1:length(angleGrid)
                    for j = 1:length(angleGrid)
            
                        arrayResponseVector1 = exp(-1i*pi*(0:(app.nH-1))*sin(azimGrid(i,j))*cos(elevGrid(i,j))).';
                        arrayResponseVector2 = exp(-1i*pi*(0:(app.nH-1))*sin(elevGrid(i,j))).';
                        arrayResponseVector = kron(arrayResponseVector1,arrayResponseVector2);
                        BF(i,j) = abs(sum(arrayResponseVector.* h )); % Taking the dot product
                    end
                    % disp([num2str(i) ' out of ' num2str(length(angleGrid)) ]);
                end
                BF =BF./(app.nH*app.nH);
                BF = BF.^2;
            end

            if(nargin ==2)
                angleGrid= linspace(-pi/2,pi/2,app.gradations_to_plot_beam);
                [azimGrid,elevGrid] = meshgrid(angleGrid, angleGrid);
                BF = zeros(size(azimGrid));
                h = conj(app.h_AP2RIS).*exp(-1i*psi_sweep);
            
                for i = 1:length(angleGrid)
                    for j = 1:length(angleGrid)
            
                        arrayResponseVector1 = exp(-1i*pi*(0:(app.nH-1))*sin(azimGrid(i,j))*cos(elevGrid(i,j))).';
                        arrayResponseVector2 = exp(-1i*pi*(0:(app.nH-1))*sin(elevGrid(i,j))).';
                        arrayResponseVector = kron(arrayResponseVector1,arrayResponseVector2);
                        BF(i,j) = abs(sum(arrayResponseVector.* h )); % Taking the dot product
                    end
                    % disp([num2str(i) ' out of ' num2str(length(angleGrid)) ]);
                end
                BF =BF./(app.nH*app.nH);
                BF = BF.^2;                

            end
        end

        
        function cal_psi_ideal(app)

            % inf resolution_perfect CSI
            app.Psi_optimum = -(angle(app.h_AP2RIS.*app.h_RIS2UE));

            % 1-bit resolution_perfect CSI
            app.Psi_suboptimum_1bit = zeros(size(app.Psi_optimum)); 
            for i = 1: length(app.Psi_optimum)
                if(abs(app.Psi_optimum(i))>=pi/2 )
                    app.Psi_suboptimum_1bit(i) = pi;
                end
            end
            
            % 2-bit resolution_perfect CSI
            app.Psi_suboptimum_2bit = floor(2*(1+app.Psi_optimum/pi));
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 0) = -3*pi/4;
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 1) = -pi/4;
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 2) = pi/4;
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 3) = 3*pi/4; 

         end
           
         function psi = cal_psi_proposed_algo(app)
            ss = pi/str2double(app.searchanglegranularityDropDown.Value);
            theta_sweep = ss/2 + (-pi/2:ss:pi/2-ss);
            phi_sweep   = ss/2 + (-pi/2:ss:pi/2-ss);
            
            rssi = zeros(length(theta_sweep),length(phi_sweep));
            for i = 1:length(theta_sweep)
                for j = 1: length(phi_sweep)

                    psi = app.eigen_direction_azim*sin(theta_sweep(i))*cos(phi_sweep(j)) + app.eigen_direction_elev*sin(phi_sweep(j));
                    
                    if(app.SeesweepCheckBox.Value == 1)
                        BF = BF_pattern(app,psi);
                        plot_BF_pattern(app,BF);pause(.01);cla(app.UIAxes);
                        txt = strcat('UE Search: thetaidx = ',num2str(i), ' , phiidx = ',num2str(j)); 
                        title(app.UIAxes,txt); 
                        draw_static_components(app);
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    rssi(i,j) = cal_rssi_ue(app,psi);
                end
            end
            [~,idx] = max(rssi(:));
            [theta_ue_proposedalgo_idx,phi_ue_proposedalgo_idx] = ind2sub(size(rssi),idx);
            theta_ue_proposedalgo = theta_sweep(theta_ue_proposedalgo_idx);
            phi_ue_proposedalgo = phi_sweep(phi_ue_proposedalgo_idx);

            app.Psi_proposedalgo = app.eigen_direction_azim*sin(theta_ue_proposedalgo)*cos(phi_ue_proposedalgo) + app.eigen_direction_elev*sin(phi_ue_proposedalgo);
            app.Psi_proposedalgo = mod(app.Psi_proposedalgo,2*pi);
            for i = 1: length(app.Psi_proposedalgo)
                if(app.Psi_proposedalgo(i)>pi)
                    app.Psi_proposedalgo(i) = app.Psi_proposedalgo(i)- 2*pi; 
                end
            end

            % 1-bit resolution_perfect CSI
            app.Psi_proposedalgo_1bit = zeros(size(app.Psi_proposedalgo)); 
            for i = 1: length(app.Psi_proposedalgo)
                if(abs(app.Psi_proposedalgo(i))>=pi/2 )
                    app.Psi_proposedalgo_1bit(i) = pi;
                end
            end
            
            % 2-bit resolution_perfect CSI
            app.Psi_proposedalgo_2bit = floor(2*(1+app.Psi_proposedalgo/pi));
            app.Psi_proposedalgo_2bit(app.Psi_suboptimum_2bit == 0) = -3*pi/4;
            app.Psi_proposedalgo_2bit(app.Psi_suboptimum_2bit == 1) = -pi/4;
            app.Psi_proposedalgo_2bit(app.Psi_suboptimum_2bit == 2) = pi/4;
            app.Psi_proposedalgo_2bit(app.Psi_suboptimum_2bit == 3) = 3*pi/4; 

        end
        
        function rssi = cal_rssi_ue(app,psi)
            % This function calculates the RSSI(Ref Signal Received Power)

            RIS_cophasor = exp(1i*psi);
            rssi = (abs(sum(app.h_AP2RIS.*RIS_cophasor.*app.h_RIS2UE))).^2;

        end
        function display_nontunable_params(app)
            app.ImpingingAzimuthdegEditField.Value = app.theta_i;
            app.ImpingingElevationdegEditField.Value = app.phi_i;
            app.d_AP2RISmEditField.Value = app.d_AP2RIS;
            app.heightofRISmEditField.Value = app.h_RIS;
            app.nHEditField.Value = app.nH;
        end


     end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ApplyButtonPushed(app, event)

        end

        % Value changing function: UEAzimuthKnob
        function UEAzimuthKnobValueChanging(app, event)
            changingValue = event.Value;
            app.UEAzimuthKnob.Value = changingValue;
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Value changing function: UEElevationKnob_2
        function UEElevationKnob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.UEElevationKnob_2.Value = changingValue;
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Value changing function: d_RIS2UESlider
        function d_RIS2UESliderValueChanging(app, event)
            changingValue = event.Value;
            app.d_RIS2UESlider.Value = changingValue;
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Callback function
        function AlgorithmButtonGroupSelectionChanged(app, event)
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Callback function
        function AngleresolutionofRISButtonGroupSelectionChanged(app, event)
            % selectedButton = app.AngleresolutionofRISButtonGroup.SelectedObject;
            % app.AngleresolutionofRISButtonGroup. = changingValue;
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Value changed function: searchanglegranularityDropDown
        function searchanglegranularityDropDownValueChanged(app, event)
            % value = app.searchanglegranularityDropDown.Value;
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            cal_psi_ideal(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ImpingingAzimuthdegEditField
        function ImpingingAzimuthdegEditFieldValueChanged(app, event)
            % value = app.ImpingingAzimuthdegEditField.Value;
            
        end

        % Selection changed function: AngleresolutionofRISButtonGroup
        function AngleresolutionofRISButtonGroupSelectionChanged2(app, event)
            % selectedButton = app.AngleresolutionofRISButtonGroup.SelectedObject;
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Selection changed function: AlgorithmButtonGroup
        function AlgorithmButtonGroupSelectionChanged2(app, event)
            % selectedButton = app.AlgorithmButtonGroup.SelectedObject;
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed2(app, event)
            cla(app.UIAxes)
            draw_static_components(app);
            display_nontunable_params(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BeamForming Pattern')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RISDashboardLabel = uilabel(app.UIFigure);
            app.RISDashboardLabel.Interpreter = 'latex';
            app.RISDashboardLabel.Text = 'RIS Dashboard';
            app.NonTunableParametersLabel = uilabel(app.UIFigure);
            app.NonTunableParametersLabel.Interpreter = 'latex';
            app.NonTunableParametersLabel.Text = 'Non-Tunable Parameters';
            app.UEElevationKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.UEElevationKnob_2.Limits = [-90 90];
            app.UEElevationKnob_2.MajorTicks = [-90 -60 -45 -30 0 30 45 60 90];
            app.UEElevationKnob_2.ValueChangingFcn = createCallbackFcn(app, @UEElevationKnob_2ValueChanging, true);
            app.UEAzimuthKnob = uiknob(app.UIFigure, 'continuous');
            app.UEAzimuthKnob.Limits = [-90 90];
            app.UEAzimuthKnob.MajorTicks = [-90 -60 -45 -30 0 30 45 60 90];
            app.UEAzimuthKnob.ValueChangingFcn = createCallbackFcn(app, @UEAzimuthKnobValueChanging, true);
            app.UEAzimuthKnob.Value = 30;
            app.UEElevationKnob_2Label = uilabel(app.UIFigure);
            app.UEElevationKnob_2Label.Text = 'UE Elevation';
            app.UEAzimuthKnobLabel = uilabel(app.UIFigure);
            app.UEAzimuthKnobLabel.Text = 'UE Azimuth';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Impinging Azimuth(deg)';
            app.ImpingingAzimuthdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImpingingAzimuthdegEditField.ValueChangedFcn = createCallbackFcn(app, @ImpingingAzimuthdegEditFieldValueChanged, true);
            app.ImpingingElevationdegEditFieldLabel = uilabel(app.UIFigure);
            app.ImpingingElevationdegEditFieldLabel.Text = 'Impinging Elevation(deg)';
            app.ImpingingElevationdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightofRISmEditFieldLabel = uilabel(app.UIFigure);
            app.heightofRISmEditFieldLabel.Text = 'height of RIS(m)';
            app.heightofRISmEditField = uieditfield(app.UIFigure, 'numeric');
            app.d_AP2RISmEditFieldLabel = uilabel(app.UIFigure);
            app.d_AP2RISmEditFieldLabel.Text = 'd_AP2RIS(m)';
            app.d_AP2RISmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nHEditFieldLabel = uilabel(app.UIFigure);
            app.nHEditFieldLabel.Text = 'nH';
            app.nHEditField = uieditfield(app.UIFigure, 'numeric');
            app.nHEditField.Value = 16;
            app.searchanglegranularityDropDownLabel = uilabel(app.UIFigure);
            app.searchanglegranularityDropDownLabel.Text = 'search angle granularity';
            app.searchanglegranularityDropDown = uidropdown(app.UIFigure);
            app.searchanglegranularityDropDown.Items = {'8', '16', '32', '64'};
            app.searchanglegranularityDropDown.ValueChangedFcn = createCallbackFcn(app, @searchanglegranularityDropDownValueChanged, true);
            app.searchanglegranularityDropDown.Value = '8';
            app.d_RIS2UESlider = uislider(app.UIFigure);
            app.d_RIS2UESlider.Limits = [0.01 4];
            app.d_RIS2UESlider.MajorTicks = [0.01 1 2 3];
            app.d_RIS2UESlider.ValueChangingFcn = createCallbackFcn(app, @d_RIS2UESliderValueChanging, true);
            app.d_RIS2UESlider.Value = 3.14;
            app.d_RIS2UESliderLabel = uilabel(app.UIFigure);
            app.d_RIS2UESliderLabel.Text = 'd_RIS2UE';
            app.AlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmButtonGroupSelectionChanged2, true);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.PerfectCSIButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.PerfectCSIButton.Text = 'Perfect CSI';
            app.PerfectCSIButton.Value = true;
            app.ProposedAlgoButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.ProposedAlgoButton.Text = 'Proposed Algo';
            app.AngleresolutionofRISButtonGroup = uibuttongroup(app.UIFigure);
            app.AngleresolutionofRISButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AngleresolutionofRISButtonGroupSelectionChanged2, true);
            app.AngleresolutionofRISButtonGroup.Title = 'Angle resolution of RIS';
            app.bitButton = uiradiobutton(app.AngleresolutionofRISButtonGroup);
            app.bitButton.Text = '1-bit ';
            app.bitButton.Value = true;
            app.bitButton_2 = uiradiobutton(app.AngleresolutionofRISButtonGroup);
            app.bitButton_2.Text = '2-bit';
            app.infiniteButton = uiradiobutton(app.AngleresolutionofRISButtonGroup);
            app.infiniteButton.Text = 'infinite';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed2, true);
            app.ApplyButton.Text = 'Apply';
            app.SeesweepCheckBox = uicheckbox(app.UIFigure);
            app.SeesweepCheckBox.Text = 'See sweep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RIS_app_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end