classdef RIS_app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        nVSpinner                       matlab.ui.control.Spinner
        nVSpinnerLabel                  matlab.ui.control.Label
        nHSpinner                       matlab.ui.control.Spinner
        nHSpinnerLabel                  matlab.ui.control.Label
        clearfigureButton               matlab.ui.control.Button
        showbeamforshowbeamtxtButton    matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SavepsivaluesButton             matlab.ui.control.Button
        channelinitialphaseSlider       matlab.ui.control.Slider
        channelinitialphaseSliderLabel  matlab.ui.control.Label
        UEElevationKnob_3               matlab.ui.control.DiscreteKnob
        UEElevationKnob_3Label          matlab.ui.control.Label
        UEAzimuthKnob_2                 matlab.ui.control.DiscreteKnob
        UEAzimuthKnob_2Label            matlab.ui.control.Label
        APElevationKnob_2               matlab.ui.control.DiscreteKnob
        APElevationKnob_2Label          matlab.ui.control.Label
        APAzimuthKnob_2                 matlab.ui.control.DiscreteKnob
        APAzimuthKnob_2Label            matlab.ui.control.Label
        d_AP2RISSliderLabel             matlab.ui.control.Label
        d_AP2RISSlider                  matlab.ui.control.Slider
        SeesweepCheckBox                matlab.ui.control.CheckBox
        ApplyButton                     matlab.ui.control.Button
        AngleresolutionofRISButtonGroup  matlab.ui.container.ButtonGroup
        infiniteButton                  matlab.ui.control.RadioButton
        bitButton_2                     matlab.ui.control.RadioButton
        bitButton                       matlab.ui.control.RadioButton
        d_RIS2UESliderLabel             matlab.ui.control.Label
        AlgorithmButtonGroup            matlab.ui.container.ButtonGroup
        ProposedAlgoButton              matlab.ui.control.RadioButton
        PerfectCSIButton                matlab.ui.control.RadioButton
        d_RIS2UESlider                  matlab.ui.control.Slider
        searchanglegranularityDropDown  matlab.ui.control.DropDown
        searchanglegranularityDropDownLabel  matlab.ui.control.Label
        RISDashboardLabel               matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        h_AP2RIS ; 
        h_RIS2UE ; 
        Psi_optimum;
        Psi_suboptimum_1bit;
        Psi_suboptimum_2bit;
        Psi_proposedalgo
        Psi_proposedalgo_1bit;
        Psi_proposedalgo_2bit;  
        eigen_direction_azim;
        eigen_direction_elev;
        nH;
        nV;
        
    end
    

    properties (Access = private,Constant)

        % eigen_direction_azim =(repelem(pi*(0:app.nH-1),app.nV)).' ;
        % eigen_direction_elev = repmat((pi*(0:app.nV-1)).',[app.nH,1]);

        lambda = physconst('LightSpeed')/(((5.150 +5.875)/2)*1e9) ; 
        gradations_to_plot_beam = 200;
        h_RIS = 1.5;

    end


    methods (Access = private)
        function  draw_static_components(app)

            
            reflected_azim = -(pi/180)*app.UEAzimuthKnob_2.Value;% It should vary from 0 to -pi/2. 0 = partition wall, -pi/2 is -y axis
            reflected_elev = (pi/180)*app.UEElevationKnob_3.Value; % It should vary from -pi/2 to +pi/2. +ve value means RIS is below the level of UE
            
            impinging_azim = (pi/180)*app.APAzimuthKnob_2.Value; % It should vary from pi/2 to 0. 0 = partition wall, pi/2 is the surface where RIS is kept.
            impinging_elev = (pi/180)*app.APElevationKnob_2.Value; % It should vary from -pi/2 to +pi/2. +ve value means RIS is below the level of AP
            
            dist_ris2ue = app.d_RIS2UESlider.Value;
            dist_ap2ris = app.d_AP2RISSlider.Value;
            

            height_of_ris  = app.h_RIS ;
            height_of_ap   = app.h_RIS + (dist_ap2ris*sin(impinging_elev));
            height_of_ue   = app.h_RIS + (dist_ris2ue*sin(reflected_elev));


            %% Draw RIS Plane

            width_RIS = app.nH*app.lambda/2;
            length_RIS = app.nV*app.lambda/2;
            y = linspace(-width_RIS/2,width_RIS/2,app.nV+1);
            a=0;b=1;c=0;d=0;
            x = linspace(-length_RIS/2,length_RIS/2,app.nH+1);
            [z2,x2]=meshgrid(x ,y );
            y2 = (1/b).*(d+(a.*x2)+(c.*z2));
            z2 = z2 + app.h_RIS ;
            surf(app.UIAxes, y2,x2,z2,'FaceColor','none','EdgeColor','k');xlabel(app.UIAxes,"x axis (in meter)");ylabel(app.UIAxes,"y axis (in meter)");zlabel(app.UIAxes,"z axis (in meter)");
            text(app.UIAxes,0,0,app.h_RIS+.5,'RIS');


            %% Draw x,y,z axes lines 

            % view(app.UIAxes,[1 -1 1]);
            axes_len = 3.14;
            line(app.UIAxes,[0,axes_len],[0,0],[0,0],'Color', 'k', 'LineWidth', 2);hold(app.UIAxes, 'on');
            line(app.UIAxes,[0,0],[-axes_len,axes_len],[0,0],'Color', 'k', 'LineWidth', 2);hold(app.UIAxes, 'on');
            line(app.UIAxes,[0,0],[0,0],[0,axes_len],'Color', 'k', 'LineWidth', 2);hold(app.UIAxes, 'on');
            axis(app.UIAxes,"square");
            
            %% Draw protractors in Vertical and horizontal planes
        
            srcImg = imrotate(imread("protractor_azim_ss.png"),-90);

            [xx, yy] =meshgrid(0:4, 4:-1:-4);
            zz = zeros(size(xx));  % set the z value

            w = warp(xx, yy, zz, srcImg);
            w.Parent=app.UIAxes;
            w.FaceAlpha = 0.2;

            delete(gcf);

            hold(app.UIAxes, 'on');

            srcImg = imrotate(imread("protractor_elev_ss.png"),-90);
            [xx, zz] =meshgrid(0:2, -2:2);
            zz = zz+height_of_ris;
            yy = zeros(size(xx));  % set the z value
            % zz(zz<0) = 0;
            w = warp(xx, yy, zz, srcImg);
            w.FaceAlpha = 0.2;
            w.Parent=app.UIAxes;
            delete(gcf);

            hold(app.UIAxes, 'on');  
            grid(app.UIAxes, 'on');


            %% Draw LoS ray - RIS to UE(Negative azim angle)

            [x_source,y_source,z_source] = sph2cart(reflected_azim,reflected_elev,dist_ris2ue);
            hold(app.UIAxes, 'on');
            quiver3(app.UIAxes,0,0,height_of_ris,x_source,y_source,z_source,1,'LineWidth',3,'Color','r');
            text(app.UIAxes,x_source,y_source,height_of_ue,'UE');
            hold(app.UIAxes, 'on');
            stem3(app.UIAxes,x_source,y_source,height_of_ue,"filled",'LineWidth',2,'Color','g');
            
            %% Draw LoS ray - AP to RIS(Positive azim angle)
            
            
            [x_source,y_source,z_source] = sph2cart(impinging_azim,impinging_elev,dist_ap2ris);
            hold(app.UIAxes, 'on');
            quiver3(app.UIAxes,x_source,y_source,height_of_ap,-x_source,-y_source,height_of_ris-height_of_ap,1,'LineWidth',3,'Color','r');
            text(app.UIAxes,x_source,y_source,height_of_ap,'AP');
            hold(app.UIAxes, 'on');
            stem3(app.UIAxes,x_source,y_source,height_of_ap,"filled",'LineWidth',2,'Color','g');

            % view(app.UIAxes,[1 -1 1]);
        end
        
        function h = LosChan256x1_AP2RIS(app)
            
            azim = (pi/180)*app.APAzimuthKnob_2.Value;
            elev = (pi/180)*app.APElevationKnob_2.Value;

            arv1 = exp(-1i*pi*(0:(app.nH-1))*sin(azim)*cos(elev)).'; % RIS Reflector Array Phase Response
            arv2 = exp(-1i*pi*(0:(app.nV-1))*sin(elev)).';
            arv  = kron(arv1,arv2);
            initial_random_phase = exp(1i*(pi/180)*app.channelinitialphaseSlider.Value);
            h  = arv*initial_random_phase;

        end

        function h = LosChan1x256_RIS2UE(app) % size(data) = [ 256x1 ] 

            azim = -(pi/180)*app.UEAzimuthKnob_2.Value;
            elev = (pi/180)*app.UEElevationKnob_3.Value;

            arv1 = exp(-1i*pi*(0:(app.nH-1))*sin(azim)*cos(elev)).'; % RIS Reflector Array Phase Response
            arv2 = exp(-1i*pi*(0:(app.nV-1))*sin(elev)).';
            h  = kron(arv1,arv2);

        end

        function generate_channels(app)
            app.h_AP2RIS = LosChan256x1_AP2RIS(app);
            app.h_RIS2UE = LosChan1x256_RIS2UE(app);
        end


        function plot_BF_pattern(app,BF)%(BF,height_RIS)

            angleGrid= linspace(-pi/2,pi/2,app.gradations_to_plot_beam);
            [azimGrid,elevGrid] = meshgrid(angleGrid, angleGrid);
            % norm_BF = BF/max(max(BF));
            norm_BF = BF;
            magnification_factor = 4;
            [X, Y, Z]   = sph2cart(-azimGrid,-elevGrid, magnification_factor*norm_BF);
            % colormap turbo

            % For opaque Beam shape
            s = surf(app.UIAxes,X,Y,Z+app.h_RIS, [ 0 0 .5],'LineStyle','none');
            app.UIAxes.XLim  = [0,4];
            app.UIAxes.YLim  = [-4,4];
            app.UIAxes.ZLim  = [-1,5];

            s.FaceColor = [0.6 0.9 0.8];

            l1 = light(app.UIAxes);
            l1.Position = [1 1 1];
            l1.Style = 'local';
            l1.Color = [1 1 1];


            % l2 = light(app.UIAxes);
            % l2.Position = [-1 -1 1];
            % l2.Style = 'local';
            % l2.Color = [1 1 1];

            % l3 = light(app.UIAxes);
            % l3.Position = [0 0 -1];
            % l3.Style = 'local';
            % l3.Color = [1 1 1];
 
            % shading(app.UIAxes,'interp')


            % For transparent Beam shape

            % BFsurface = surf(app.UIAxes,X,Y,Z+app.h_RIS, norm_BF);
            % view([1 1 1])
            % set(BFsurface,'LineStyle','none','FaceAlpha',0.5,'Tag','3D polar plot');


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        function BF = BF_pattern(app,psi_sweep)

            if(nargin ==1)
                algo = app.AlgorithmButtonGroup.SelectedObject.Text;
                res = app.AngleresolutionofRISButtonGroup.SelectedObject.Text;
                if(strcmp(algo,"Perfect CSI"))
                    algo =1;
                else
                    algo =2;
                end
    
                if(strcmp(res,"1-bit "))
                    res =1;
                elseif(strcmp(res,"2-bit"))
                    res =2;
                else
                    res =3;
                end


                if(algo == 1)  % Perfect CSI Algorithm 
                    if(res == 1)
                        Psi = app.Psi_suboptimum_1bit;
                    elseif(res ==2)
                        Psi = app.Psi_suboptimum_2bit;
                    else
                        Psi = app.Psi_optimum;
                    end
                else    % Proposed Algorithm
                    if(res == 1)
                        Psi = app.Psi_proposedalgo_1bit;
                    elseif(res ==2)
                        Psi = app.Psi_proposedalgo_2bit;
                    else
                        Psi = app.Psi_proposedalgo;
                    end
                end
    
                angleGrid= linspace(-pi/2,pi/2,app.gradations_to_plot_beam);
                [azimGrid,elevGrid] = meshgrid(angleGrid, angleGrid);
                BF = zeros(size(azimGrid));
                h = conj(app.h_AP2RIS).*exp(-1i*Psi);
            
                for i = 1:length(angleGrid)
                    for j = 1:length(angleGrid)
            
                        arrayResponseVector1 = exp(-1i*pi*(0:(app.nH-1))*sin(azimGrid(i,j))*cos(elevGrid(i,j))).';
                        arrayResponseVector2 = exp(-1i*pi*(0:(app.nV-1))*sin(elevGrid(i,j))).';
                        arrayResponseVector = kron(arrayResponseVector1,arrayResponseVector2);
                        BF(i,j) = abs(sum(arrayResponseVector.* h )); % Taking the dot product
                    end
                    % disp([num2str(i) ' out of ' num2str(length(angleGrid)) ]);
                end
                BF =BF./(app.nH*app.nV);
                BF = BF.^2;
            end

            if(nargin ==2)
                angleGrid= linspace(-pi/2,pi/2,app.gradations_to_plot_beam);
                [azimGrid,elevGrid] = meshgrid(angleGrid, angleGrid);
                BF = zeros(size(azimGrid));
                h = conj(app.h_AP2RIS).*exp(-1i*psi_sweep);
            
                for i = 1:length(angleGrid)
                    for j = 1:length(angleGrid)
            
                        arrayResponseVector1 = exp(-1i*pi*(0:(app.nH-1))*sin(azimGrid(i,j))*cos(elevGrid(i,j))).';
                        arrayResponseVector2 = exp(-1i*pi*(0:(app.nV-1))*sin(elevGrid(i,j))).';
                        arrayResponseVector = kron(arrayResponseVector1,arrayResponseVector2);
                        BF(i,j) = abs(sum(arrayResponseVector.* h )); % Taking the dot product
                    end
                    % disp([num2str(i) ' out of ' num2str(length(angleGrid)) ]);
                end
                BF =BF./(app.nH*app.nV);
                BF = BF.^2;                

            end
        end

        
        function cal_psi_ideal(app)

            % inf resolution_perfect CSI
            app.Psi_optimum = -(angle(app.h_AP2RIS.*app.h_RIS2UE));

            % 1-bit resolution_perfect CSI
            app.Psi_suboptimum_1bit = zeros(size(app.Psi_optimum)); 
            for i = 1: length(app.Psi_optimum)
                if(abs(app.Psi_optimum(i))>=pi/2 )
                    app.Psi_suboptimum_1bit(i) = pi;
                end
            end
            
            % 2-bit resolution_perfect CSI
            app.Psi_suboptimum_2bit = floor(2*(1+app.Psi_optimum/pi));
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 0) = -3*pi/4;
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 1) = -pi/4;
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 2) = pi/4;
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 3) = 3*pi/4; 
            app.Psi_suboptimum_2bit(app.Psi_suboptimum_2bit == 4) = -3*pi/4;
         end
           
         function psi = cal_psi_proposed_algo(app)
             impinging_azim= (pi/180)*app.APAzimuthKnob_2.Value;
             impinging_elev= (pi/180)*app.APElevationKnob_2.Value;

             app.eigen_direction_azim =(repelem(pi*(0:app.nH-1),app.nV)).' ;
             app.eigen_direction_elev = repmat((pi*(0:app.nV-1)).',[app.nH,1]);

             if(strcmp(app.searchanglegranularityDropDown.Value,'infinite'))
                    theta_ue_proposedalgo = -(pi/180)*app.UEAzimuthKnob_2.Value;
                    phi_ue_proposedalgo =   (pi/180)* app.UEElevationKnob_3.Value;
             else

                    ss = pi/str2double(app.searchanglegranularityDropDown.Value);
                    theta_sweep = ss/2 + (-pi/2:ss:pi/2-ss);
                    phi_sweep   = ss/2 + (-pi/2:ss:pi/2-ss);
                    
                    rssi = zeros(length(theta_sweep),length(phi_sweep));
                    for i = 1:length(theta_sweep)
                        for j = 1: length(phi_sweep)
        
                            psi =  app.eigen_direction_azim*(sin(theta_sweep(i))*cos(phi_sweep(j))  +  sin(impinging_azim)*cos(impinging_elev)) + ...
                                   app.eigen_direction_elev*(sin(phi_sweep(j))+sin(impinging_elev));
                            psi = mod(psi,2*pi);
                                        % convert it to range of (-pi,pi)
                            for iter = 1: length(psi)
                                if(psi(iter)>pi)
                                    psi(iter) = psi(iter)-2*pi;
                                end
                            end
                            psi = quantize_psi(app,psi);

                            if(app.SeesweepCheckBox.Value == 1)
                                BF = BF_pattern(app,psi);
                                plot_BF_pattern(app,BF);pause(.001);cla(app.UIAxes);
                                txt = strcat('UE Search: thetaidx = ',num2str(i), ' , phiidx = ',num2str(j)); 
                                title(app.UIAxes,txt); 
                                draw_static_components(app);

                                % Draw RIS profile
                                angle = (180/pi)*psi;
                                angle = reshape(angle,[app.nV,app.nH]);
                                imagesc(app.UIAxes4,angle,[0 360]);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");
                                %%%%%%%%%%%%%%%%%%%%
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            rssi(i,j) = cal_rssi_ue(app,psi);
                        end
                    end
                    [~,idx] = max(rssi(:));
                    [theta_ue_proposedalgo_idx,phi_ue_proposedalgo_idx] = ind2sub(size(rssi),idx);
                    theta_ue_proposedalgo = theta_sweep(theta_ue_proposedalgo_idx);
                    phi_ue_proposedalgo = phi_sweep(phi_ue_proposedalgo_idx);
            end
            app.Psi_proposedalgo =  app.eigen_direction_azim*(sin(theta_ue_proposedalgo)*cos(phi_ue_proposedalgo)+sin(impinging_azim)*cos(impinging_elev)) ...
                                  + app.eigen_direction_elev*(sin(phi_ue_proposedalgo)+sin(impinging_elev));
    
            app.Psi_proposedalgo = mod(app.Psi_proposedalgo,2*pi);
            for i = 1: length(app.Psi_proposedalgo)
                if(app.Psi_proposedalgo(i)>pi)
                    app.Psi_proposedalgo(i) = app.Psi_proposedalgo(i)- 2*pi; 
                end
            end

            % 1-bit resolution_perfect CSI
            app.Psi_proposedalgo_1bit = zeros(size(app.Psi_proposedalgo)); 
            for i = 1: length(app.Psi_proposedalgo)
                if(abs(app.Psi_proposedalgo(i))>=pi/2 )
                    app.Psi_proposedalgo_1bit(i) = pi;
                end
            end
            
            % 2-bit resolution_perfect CSI
            app.Psi_proposedalgo_2bit = floor(2*(1+app.Psi_proposedalgo/pi));
            app.Psi_proposedalgo_2bit(app.Psi_proposedalgo_2bit == 0) = -3*pi/4;
            app.Psi_proposedalgo_2bit(app.Psi_proposedalgo_2bit == 1) = -pi/4;
            app.Psi_proposedalgo_2bit(app.Psi_proposedalgo_2bit == 2) = pi/4;
            app.Psi_proposedalgo_2bit(app.Psi_proposedalgo_2bit == 3) = 3*pi/4; 
            app.Psi_proposedalgo_2bit(app.Psi_proposedalgo_2bit == 4) = -3*pi/4; 

        end
        
        function rssi = cal_rssi_ue(app,psi)
            % This function calculates the RSSI(Ref Signal Received Power)

            RIS_cophasor = exp(1i*psi);
            rssi = (abs(sum(app.h_AP2RIS.*RIS_cophasor.*app.h_RIS2UE))).^2;

        end


        function plot_RIS_profile(app)
            clims = [0 360];
            cal_psi_ideal(app);

            app.SeesweepCheckBox.Value  = 0;
            cal_psi_proposed_algo(app);
            angle_proposedalgo = (180/pi)*reshape(app.Psi_proposedalgo,[app.nV, app.nH]);
            angle_perfcsi = (180/pi)*reshape(app.Psi_optimum,[app.nV, app.nH]);
            for  i = 1:numel(angle_proposedalgo)
                if(angle_proposedalgo(i)<0)
                    angle_proposedalgo(i) = 360+angle_proposedalgo(i);
                end
            end
            for  i = 1:numel(angle_perfcsi)
                 if(angle_perfcsi(i)<0)
                    angle_perfcsi(i) = 360+angle_perfcsi(i);
                end                   
            end
            imagesc(app.UIAxes2,angle_perfcsi,clims);colorbar(app.UIAxes2);axis(app.UIAxes2,"tight");
            imagesc(app.UIAxes2_2,angle_proposedalgo,clims);colorbar(app.UIAxes2_2);axis(app.UIAxes2_2,"tight");

            diff = angle_proposedalgo-angle_perfcsi;
            for  i = 1:numel(diff)
                 if(diff(i)<0)
                    diff(i) = 360+diff(i);
                end                   
            end
            imagesc(app.UIAxes2_3,diff,clims);colorbar(app.UIAxes2_3);axis(app.UIAxes2_3,"tight");


            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text, "Proposed Algo")) 

                if(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"1-bit "))
                    angle = (180/pi)*reshape(app.Psi_proposedalgo_1bit,[app.nV, app.nH]);
                    for  i = 1:numel(angle)
                        if(angle(i)<0)
                            angle(i) = 360+angle(i);
                        end                   
                    end
                    imagesc(app.UIAxes4,angle,clims);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");
                elseif(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"2-bit"))
                    angle = (180/pi)*reshape(app.Psi_proposedalgo_2bit,[app.nV, app.nH]);
                    for  i = 1:numel(angle)
                        if(angle(i)<0)
                            angle(i) = 360+angle(i);
                        end                   
                    end
                    imagesc(app.UIAxes4,angle,clims);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");
                else
                    angle = (180/pi)*reshape(app.Psi_proposedalgo,[app.nV, app.nH]);
                    for  i = 1:numel(angle)
                        if(angle(i)<0)
                            angle(i) = 360+angle(i);
                        end                   
                    end
                    imagesc(app.UIAxes4,angle,clims);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");
                end
            end

            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text ,"Perfect CSI"))


                if(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"1-bit "))
                    angle = (180/pi)*reshape(app.Psi_suboptimum_1bit,[app.nV, app.nH]);
                    for  i = 1:numel(angle)
                        if(angle(i)<0)
                            angle(i) = 360+angle(i);
                        end                   
                    end
                    imagesc(app.UIAxes4,angle,clims);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");

                elseif(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"2-bit"))
                    angle = (180/pi)*reshape(app.Psi_suboptimum_2bit,[app.nV, app.nH]);
                    for  i = 1:numel(angle)
                        if(angle(i)<0)
                            angle(i) = 360+angle(i);
                        end                   
                    end
                    imagesc(app.UIAxes4,angle,clims);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");
                else
                    angle = (180/pi)*reshape(app.Psi_optimum,[app.nV, app.nH]);
                    for  i = 1:numel(angle)
                        if(angle(i)<0)
                            angle(i) = 360+angle(i);
                        end                   
                    end
                    imagesc(app.UIAxes4,angle,clims);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");               
                end

            end

        end
        
        function run_all(app)
            cla(app.UIAxes);
            app.nH = app.nHSpinner.Value;
            app.nV = app.nVSpinner.Value;
            draw_static_components(app);
           
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
            plot_RIS_profile(app);
        end
        
        function quantized_psi = quantize_psi(app,psi)


            quantized_psi = zeros(size(psi));

            if(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"1-bit "))
                for i = 1: length(psi)
                    if(abs(psi(i))>=pi/2 )
                        quantized_psi(i) = pi;
                    end
                end

            elseif(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"2-bit"))

                quantized_psi = floor(2*(1+psi/pi));
                quantized_psi(quantized_psi == 0) = -3*pi/4;
                quantized_psi(quantized_psi == 1) = -pi/4;
                quantized_psi(quantized_psi == 2) = pi/4;
                quantized_psi(quantized_psi == 3) = 3*pi/4; 
                quantized_psi(quantized_psi == 4) = -3*pi/4;
            else
                quantized_psi = psi;
            end
        end
     end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ApplyButtonPushed(app, event)

        end

        % Callback function
        function UEAzimuthKnobValueChanging(app, event)

        end

        % Callback function
        function UEElevationKnob_2ValueChanging(app, event)

        end

        % Value changing function: d_RIS2UESlider
        function d_RIS2UESliderValueChanging(app, event)
            changingValue = event.Value;
            app.d_RIS2UESlider.Value = changingValue;
            run_all(app);

        end

        % Callback function
        function AlgorithmButtonGroupSelectionChanged(app, event)
            run_all(app);
        end

        % Callback function
        function AngleresolutionofRISButtonGroupSelectionChanged(app, event)
            run_all(app);
        end

        % Value changed function: searchanglegranularityDropDown
        function searchanglegranularityDropDownValueChanged(app, event)
            run_all(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Callback function
        function ImpingingAzimuthdegEditFieldValueChanged(app, event)

        end

        % Selection changed function: AngleresolutionofRISButtonGroup
        function AngleresolutionofRISButtonGroupSelectionChanged2(app, event)
            run_all(app);
        end

        % Selection changed function: AlgorithmButtonGroup
        function AlgorithmButtonGroupSelectionChanged2(app, event)
            run_all(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed2(app, event)
            run_all(app);
        end

        % Callback function
        function ExitButtonPushed(app, event)

        end

        % Callback function
        function APAzimuthKnobValueChanging(app, event)
            run_all(app);
        end

        % Callback function
        function APElevationKnobValueChanging(app, event)
            run_all(app);
        end

        % Value changing function: d_AP2RISSlider
        function d_AP2RISSliderValueChanging(app, event)
            changingValue = event.Value;
            app.d_AP2RISSlider.Value = changingValue;
            run_all(app);
        end

        % Callback function
        function height_RISSliderValueChanging(app, event)
            changingValue = event.Value;
            app.height_RISSlider.Value = changingValue;
            run_all(app);
        end

        % Value changed function: APAzimuthKnob_2
        function APAzimuthKnob_2ValueChanged(app, event)
            run_all(app);
        end

        % Value changed function: APElevationKnob_2
        function APElevationKnob_2ValueChanged(app, event)
            run_all(app);
        end

        % Value changed function: UEAzimuthKnob_2
        function UEAzimuthKnob_2ValueChanged(app, event)
            run_all(app);
        end

        % Value changed function: UEElevationKnob_3
        function UEElevationKnob_3ValueChanged(app, event)
            run_all(app);
        end

        % Value changed function: channelinitialphaseSlider
        function channelinitialphaseSliderValueChanged(app, event)
            run_all(app);
        end

        % Button pushed function: SavepsivaluesButton
        function SavepsivaluesButtonPushed(app, event)
            if(isempty(app.FilenameEditField.Value))
                txt = "noname";
            else

                txt = app.FilenameEditField.Value;
            end
            txt = strcat(txt,'.txt');
            % cal_psi_proposed_algo(app);
            % cal_psi_ideal(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text, "Proposed Algo")) 
                if(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"1-bit "))
                    matrix = (180/pi)*reshape(app.Psi_proposedalgo_1bit,[app.nV,app.nH]); 
                    writematrix(round(matrix),txt);
                elseif(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"2-bit"))
                    matrix = (180/pi)*reshape(app.Psi_proposedalgo_2bit,[app.nV,app.nH]); 
                    writematrix(round(matrix),txt);
                else
                    matrix = (180/pi)*reshape(app.Psi_proposedalgo,[app.nV,app.nH]); 
                    writematrix(round(matrix),txt);
                end
            else
                if(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"1-bit "))
                    matrix = (180/pi)*reshape(app.Psi_suboptimum_1bit,[app.nV,app.nH]); 
                    writematrix(round(matrix),txt);
                elseif(strcmp(app.AngleresolutionofRISButtonGroup.SelectedObject.Text,"2-bit"))
                    matrix = (180/pi)*reshape(app.Psi_suboptimum_2bit,[app.nV,app.nH]); 
                    writematrix(round(matrix),txt);
                else
                    matrix = (180/pi)*reshape(app.Psi_optimum,[app.nV,app.nH]); 
                    writematrix(round(matrix),txt);
                end
            end
        end

        % Button pushed function: showbeamforshowbeamtxtButton
        function showbeamforshowbeamtxtButtonPushed(app, event)
            angle = load("showbeam.txt");
            app.nH = size(angle,2);
            app.nV = size(angle,1);


            cla(app.UIAxes);
            draw_static_components(app);
            generate_channels(app);
            if(strcmp(app.AlgorithmButtonGroup.SelectedObject.Text,"Perfect CSI"))
                cal_psi_ideal(app);
            else
                cal_psi_proposed_algo(app);
            end
            BF = BF_pattern(app);
            plot_BF_pattern(app,BF);
            plot_RIS_profile(app);


            angle = load("showbeam.txt");
            
            % Show RIS profile 
            imagesc(app.UIAxes4,angle,[0 360]);colorbar(app.UIAxes4);axis(app.UIAxes4,"tight");

            % Calcualte BeamForming vector 
            BF = BF_pattern(app,angle(:));

            % Show BF Vector
            plot_BF_pattern(app,BF);
            
        end

        % Button pushed function: clearfigureButton
        function clearfigureButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes4)
            draw_static_components(app);
        end

        % Value changed function: nHSpinner
        function nHSpinnerValueChanged(app, event)
            value = app.nHSpinner.Value;
            run_all(app);
        end

        % Value changed function: nVSpinner
        function nVSpinnerValueChanged(app, event)
            value = app.nVSpinner.Value;
            run_all(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'hsv');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BeamForming Pattern')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            colormap(app.UIAxes, 'hsv')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Perfect CSI RIS profile(infinite resolution)')
            colormap(app.UIAxes2, 'hsv')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, {'Proposed Algo RIS profile(Infinite '; ' resolution)'})
            colormap(app.UIAxes2_2, 'hsv')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Perfect CSI minus Proposed Algorithm')
            colormap(app.UIAxes2_3, 'hsv')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'RIS Profile')
            colormap(app.UIAxes4, 'hsv')
            app.RISDashboardLabel = uilabel(app.UIFigure);
            app.RISDashboardLabel.Interpreter = 'latex';
            app.RISDashboardLabel.Text = 'RIS Dashboard';
            app.searchanglegranularityDropDownLabel = uilabel(app.UIFigure);
            app.searchanglegranularityDropDownLabel.Text = 'search angle granularity';
            app.searchanglegranularityDropDown = uidropdown(app.UIFigure);
            app.searchanglegranularityDropDown.Items = {'8', '16', '32', '64', 'infinite'};
            app.searchanglegranularityDropDown.ValueChangedFcn = createCallbackFcn(app, @searchanglegranularityDropDownValueChanged, true);
            app.searchanglegranularityDropDown.Value = '8';
            app.d_RIS2UESlider = uislider(app.UIFigure);
            app.d_RIS2UESlider.Limits = [0.01 4];
            app.d_RIS2UESlider.MajorTicks = [0.01 1 2 3 4];
            app.d_RIS2UESlider.ValueChangingFcn = createCallbackFcn(app, @d_RIS2UESliderValueChanging, true);
            app.d_RIS2UESlider.Value = 3.14;
            app.AlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmButtonGroupSelectionChanged2, true);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.PerfectCSIButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.PerfectCSIButton.Text = 'Perfect CSI';
            app.PerfectCSIButton.Value = true;
            app.ProposedAlgoButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.ProposedAlgoButton.Text = 'Proposed Algo';
            app.d_RIS2UESliderLabel = uilabel(app.UIFigure);
            app.d_RIS2UESliderLabel.Text = 'd_RIS2UE';
            app.AngleresolutionofRISButtonGroup = uibuttongroup(app.UIFigure);
            app.AngleresolutionofRISButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AngleresolutionofRISButtonGroupSelectionChanged2, true);
            app.AngleresolutionofRISButtonGroup.Title = 'Angle resolution of RIS';
            app.bitButton = uiradiobutton(app.AngleresolutionofRISButtonGroup);
            app.bitButton.Text = '1-bit ';
            app.bitButton.Value = true;
            app.bitButton_2 = uiradiobutton(app.AngleresolutionofRISButtonGroup);
            app.bitButton_2.Text = '2-bit';
            app.infiniteButton = uiradiobutton(app.AngleresolutionofRISButtonGroup);
            app.infiniteButton.Text = 'infinite';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed2, true);
            app.ApplyButton.Text = 'Apply';
            app.SeesweepCheckBox = uicheckbox(app.UIFigure);
            app.SeesweepCheckBox.Text = 'See sweep';
            app.d_AP2RISSlider = uislider(app.UIFigure);
            app.d_AP2RISSlider.Limits = [0.01 4];
            app.d_AP2RISSlider.MajorTicks = [0.01 1 2 3 4];
            app.d_AP2RISSlider.ValueChangingFcn = createCallbackFcn(app, @d_AP2RISSliderValueChanging, true);
            app.d_AP2RISSlider.Value = 3.14;
            app.d_AP2RISSliderLabel = uilabel(app.UIFigure);
            app.d_AP2RISSliderLabel.Text = 'd_AP2RIS';
            app.APAzimuthKnob_2Label = uilabel(app.UIFigure);
            app.APAzimuthKnob_2Label.Text = 'AP Azimuth';
            app.APAzimuthKnob_2 = uiknob(app.UIFigure, 'discrete');
            app.APAzimuthKnob_2.Items = {'-90', '-60', '-45', '-30', '-10', '0', '10', '30', '45', '60', '90', '2', '3'};
            app.APAzimuthKnob_2.ItemsData = [-90 -60 -45 -30 -10 0 10 30 45 60 90];
            app.APAzimuthKnob_2.ValueChangedFcn = createCallbackFcn(app, @APAzimuthKnob_2ValueChanged, true);
            app.APAzimuthKnob_2.Value = -45;
            app.APElevationKnob_2Label = uilabel(app.UIFigure);
            app.APElevationKnob_2Label.Text = 'AP Elevation';
            app.APElevationKnob_2 = uiknob(app.UIFigure, 'discrete');
            app.APElevationKnob_2.Items = {'-90', '-60', '-45', '-30', '-10', '0', '10', '30', '45', '60', '90', '2', '3'};
            app.APElevationKnob_2.ItemsData = [-90 -60 -45 -30 -10 0 10 30 45 60 90];
            app.APElevationKnob_2.ValueChangedFcn = createCallbackFcn(app, @APElevationKnob_2ValueChanged, true);
            app.APElevationKnob_2.Value = -10;
            app.UEAzimuthKnob_2Label = uilabel(app.UIFigure);
            app.UEAzimuthKnob_2Label.Text = 'UE Azimuth';
            app.UEAzimuthKnob_2 = uiknob(app.UIFigure, 'discrete');
            app.UEAzimuthKnob_2.Items = {'-90', '-60', '-45', '-30', '-10', '0', '10', '30', '45', '60', '90', '2', '3'};
            app.UEAzimuthKnob_2.ItemsData = [-90 -60 -45 -30 -10 0 10 30 45 60 90];
            app.UEAzimuthKnob_2.ValueChangedFcn = createCallbackFcn(app, @UEAzimuthKnob_2ValueChanged, true);
            app.UEAzimuthKnob_2.Value = 30;
            app.UEElevationKnob_3Label = uilabel(app.UIFigure);
            app.UEElevationKnob_3Label.Text = 'UE Elevation';
            app.UEElevationKnob_3 = uiknob(app.UIFigure, 'discrete');
            app.UEElevationKnob_3.Items = {'-90', '-60', '-45', '-30', '-10', '0', '10', '30', '45', '60', '90', '2', '3'};
            app.UEElevationKnob_3.ItemsData = [-90 -60 -45 -30 -10 0 10 30 45 60 90];
            app.UEElevationKnob_3.ValueChangedFcn = createCallbackFcn(app, @UEElevationKnob_3ValueChanged, true);
            app.UEElevationKnob_3.Value = 45;
            app.channelinitialphaseSliderLabel = uilabel(app.UIFigure);
            app.channelinitialphaseSliderLabel.Text = 'channel initial phase';
            app.channelinitialphaseSlider = uislider(app.UIFigure);
            app.channelinitialphaseSlider.Limits = [0 360];
            app.channelinitialphaseSlider.ValueChangedFcn = createCallbackFcn(app, @channelinitialphaseSliderValueChanged, true);
            app.SavepsivaluesButton = uibutton(app.UIFigure, 'push');
            app.SavepsivaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SavepsivaluesButtonPushed, true);
            app.SavepsivaluesButton.Text = 'Save psi values';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.showbeamforshowbeamtxtButton = uibutton(app.UIFigure, 'push');
            app.showbeamforshowbeamtxtButton.ButtonPushedFcn = createCallbackFcn(app, @showbeamforshowbeamtxtButtonPushed, true);
            app.showbeamforshowbeamtxtButton.Text = 'show beam for showbeam.txt';
            app.clearfigureButton = uibutton(app.UIFigure, 'push');
            app.clearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @clearfigureButtonPushed, true);
            app.clearfigureButton.Text = 'clear figure';
            app.nHSpinnerLabel = uilabel(app.UIFigure);
            app.nHSpinnerLabel.Text = 'nH';
            app.nHSpinner = uispinner(app.UIFigure);
            app.nHSpinner.Step = 8;
            app.nHSpinner.Limits = [8 64];
            app.nHSpinner.RoundFractionalValues = 'on';
            app.nHSpinner.ValueChangedFcn = createCallbackFcn(app, @nHSpinnerValueChanged, true);
            app.nHSpinner.Value = 16;
            app.nVSpinnerLabel = uilabel(app.UIFigure);
            app.nVSpinnerLabel.Text = 'nV';
            app.nVSpinner = uispinner(app.UIFigure);
            app.nVSpinner.Step = 8;
            app.nVSpinner.Limits = [8 64];
            app.nVSpinner.RoundFractionalValues = 'on';
            app.nVSpinner.ValueChangedFcn = createCallbackFcn(app, @nVSpinnerValueChanged, true);
            app.nVSpinner.Value = 16;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RIS_app_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end